classdef LoginApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        V10UIFigure        matlab.ui.Figure
        EditField          matlab.ui.control.EditField
        Image              matlab.ui.control.Image
        PasswordEditField  matlab.ui.control.EditField
        Label_2            matlab.ui.control.Label
        UsernameEditField  matlab.ui.control.EditField
        Label              matlab.ui.control.Label
        LoginButton        matlab.ui.control.Button
    end

    
    properties (Access = private)
        Property % Description
        correctUsername = 'admin';
        correctPassword = '123456';
        
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoginButton
        function LoginButtonPushed(app, event)
            
            username = app.UsernameEditField.Value;
            password = app.PasswordEditField.Value;

            if strcmp(username, app.correctUsername) && strcmp(password, app.correctPassword)
                % 登录成功
                disp('登录成功');
                
                % 打开主界面App
               MainApp;  % 假设主界面叫 MainApp.mlapp

                % 关闭登录界面
                delete(app);
            else
                uialert(app.V10UIFigure, '账号或密码错误！', '登录失败');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.V10UIFigure = uifigure('Visible', 'off');
            app.V10UIFigure.Name = '基于强化学习的前列腺超声消融仿真平台V1.0';
            app.V10UIFigure.Icon = fullfile(pathToMLAPP, 'data', 'icon.png');
            app.LoginButton = uibutton(app.V10UIFigure, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LoginButtonPushed, true);
            app.LoginButton.Text = '登录';
            app.Label = uilabel(app.V10UIFigure);
            app.Label.Text = '账号';
            app.UsernameEditField = uieditfield(app.V10UIFigure, 'text');
            app.Label_2 = uilabel(app.V10UIFigure);
            app.Label_2.Text = '密码';
            app.PasswordEditField = uieditfield(app.V10UIFigure, 'text');
            app.Image = uiimage(app.V10UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'data', '1.png');
            app.EditField = uieditfield(app.V10UIFigure, 'text');
            app.EditField.Value = '基于强化学习的前列腺超声消融仿真平台V1.0';
            % Show the figure after all components are created
            app.V10UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LoginApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.V10UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.V10UIFigure)
        end
    end
end